classdef FashionClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ImageAxes      matlab.ui.control.UIAxes
        LoadButton     matlab.ui.control.Button
        PercentLabel   matlab.ui.control.Label
        AccuracyLabel  matlab.ui.control.Label
        TypeLabel      matlab.ui.control.Label
        ClothLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        net = load('shufflemat.mat');
    end
    
    methods (Access = private)
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);

                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               ImageRead = imread(fname);
               ImageRead = imresize(ImageRead, [224 224]);
               [predclass, predscore] = classify(app.net.net, ImageRead);
               app.PercentLabel.Text = num2str(100*max(predscore)) + "%";
               app.ClothLabel.Text = predclass;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Ali Egemen Taşören Fashion Classifier';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load an Image';
            app.PercentLabel = uilabel(app.UIFigure);
            app.PercentLabel.Text = 'Percent';
            app.AccuracyLabel = uilabel(app.UIFigure);
            app.AccuracyLabel.Text = 'Accuracy:';
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'Type:';
            app.ClothLabel = uilabel(app.UIFigure);
            app.ClothLabel.Text = 'Cloth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FashionClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end